classdef PRTConfigureDualScrn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StimulusConfigurationModeUIFigure  matlab.ui.Figure
        FixationCrossPanel          matlab.ui.container.Panel
        SizeSliderLabel             matlab.ui.control.Label
        SizeSlider                  matlab.ui.control.Slider
        ThicknessSliderLabel        matlab.ui.control.Label
        ThicknessSlider             matlab.ui.control.Slider
        HeadPanel                   matlab.ui.container.Panel
        SizeSlider_2Label           matlab.ui.control.Label
        SizeSlider_2                matlab.ui.control.Slider
        ThicknessSlider_2Label      matlab.ui.control.Label
        ThicknessSlider_2           matlab.ui.control.Slider
        EyesPanel                   matlab.ui.container.Panel
        SizeSlider_3Label           matlab.ui.control.Label
        SizeSlider_3                matlab.ui.control.Slider
        YPositionSliderLabel        matlab.ui.control.Label
        YPositionSlider             matlab.ui.control.Slider
        DistanceSliderLabel         matlab.ui.control.Label
        DistanceSlider              matlab.ui.control.Slider
        NosePanel                   matlab.ui.container.Panel
        ShortNoseHeightSliderLabel  matlab.ui.control.Label
        ShortNoseHeightSlider       matlab.ui.control.Slider
        LongNoseHeightSliderLabel   matlab.ui.control.Label
        LongNoseHeightSlider        matlab.ui.control.Slider
        ThicknessSlider_3Label      matlab.ui.control.Label
        ThicknessSlider_3           matlab.ui.control.Slider
        YPositionSlider_2Label      matlab.ui.control.Label
        YPositionSlider_2           matlab.ui.control.Slider
        MouthPanel                  matlab.ui.container.Panel
        ShortMouthWidthSliderLabel  matlab.ui.control.Label
        ShortMouthWidthSlider       matlab.ui.control.Slider
        LongMouthWidthSliderLabel   matlab.ui.control.Label
        LongMouthWidthSlider        matlab.ui.control.Slider
        ThicknessSlider_4Label      matlab.ui.control.Label
        ThicknessSlider_4           matlab.ui.control.Slider
        YPositionSlider_3Label      matlab.ui.control.Label
        YPositionSlider_3           matlab.ui.control.Slider
        DisplayObjectButtonGroup    matlab.ui.container.ButtonGroup
        FixationCrossButton         matlab.ui.control.RadioButton
        FaceButton                  matlab.ui.control.RadioButton
        StimulusVersionButtonGroup  matlab.ui.container.ButtonGroup
        MouthButton                 matlab.ui.control.RadioButton
        NoseButton                  matlab.ui.control.RadioButton
        StimulusButtonGroup         matlab.ui.container.ButtonGroup
        ShortButton                 matlab.ui.control.RadioButton
        LongButton                  matlab.ui.control.RadioButton
        BackgroundButtonGroup       matlab.ui.container.ButtonGroup
        BlackButton                 matlab.ui.control.RadioButton
        WhiteButton                 matlab.ui.control.RadioButton
        DefaultButton               matlab.ui.control.Button
        DoneButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        ScaleSliderLabel            matlab.ui.control.Label
        ScaleSlider                 matlab.ui.control.Slider
        LineStylePanel              matlab.ui.container.Panel
        IndependentCheckBox         matlab.ui.control.CheckBox
        ScaleFreeCheckBox           matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        MainApp
        
        screens
        screenNumber
        screenWindowHandle
        screenWindowRect
        screenCenterCoord
        
        background
        barLength
        barThickness
        headRadius
        headThickness
        eyeRadius
        eyeDistance
        eyeYPos
        noseHeights
        noseThickness
        noseYPos
        mouthWidths
        mouthThickness
        mouthYPos
        slideScalar
        
    end
    
    methods (Access = private)
        
        function DisplaySample(app)
            if app.BlackButton.Value
                app.background = 'Black';
                backgroundColor = BlackIndex(app.screenNumber);
                foregroundColor = WhiteIndex(app.screenNumber);
            else
                app.background = 'White';
                backgroundColor = WhiteIndex(app.screenNumber);
                foregroundColor = BlackIndex(app.screenNumber);
            end
            Screen('FillRect',app.screenWindowHandle,backgroundColor)
            
            app.barLength = app.SizeSlider.Value;
            app.barThickness = app.ThicknessSlider.Value;
            
            app.headRadius=app.SizeSlider_2.Value;
            app.headThickness=app.ThicknessSlider_2.Value;
            
            app.eyeRadius=app.SizeSlider_3.Value;
            app.eyeDistance=app.DistanceSlider.Value;
            app.eyeYPos=-app.YPositionSlider.Value;
            
            app.noseHeights = [app.ShortNoseHeightSlider.Value app.LongNoseHeightSlider.Value];
            app.noseThickness=app.ThicknessSlider_3.Value;
            app.noseYPos=app.YPositionSlider_2.Value;
            
            app.mouthWidths = [app.ShortMouthWidthSlider.Value app.LongMouthWidthSlider.Value];
            app.mouthThickness=app.ThicknessSlider_4.Value;
            app.mouthYPos=app.YPositionSlider_3.Value;


            app.slideScalar = app.ScaleSlider.Value;
            scale=2^app.slideScalar;
            
            bL = app.SizeSlider.Value;
            bT = app.ThicknessSlider.Value;
            
            if app.ScaleFreeCheckBox.Value
                hT = round(app.ThicknessSlider_2.Value);
                nT = round(app.ThicknessSlider_3.Value);
                mT = round(app.ThicknessSlider_4.Value);
            else
                hT = round(app.ThicknessSlider_2.Value*scale);
                nT = round(app.ThicknessSlider_3.Value*scale);
                mT = round(app.ThicknessSlider_4.Value*scale);
            end
            
            hR = round(app.SizeSlider_2.Value*scale);
            
            eR = round(app.SizeSlider_3.Value*scale);
            eD = round(app.DistanceSlider.Value*scale);
            eY = round(app.YPositionSlider.Value*scale);
            
            nH = [round(app.ShortNoseHeightSlider.Value*scale) round(app.LongNoseHeightSlider.Value*scale)];
            nY = round(app.YPositionSlider_2.Value*scale);
            
            mW = [round(app.ShortMouthWidthSlider.Value*scale) round(app.LongMouthWidthSlider.Value*scale)];
            mY = round(app.YPositionSlider_3.Value*scale);

            if app.FixationCrossButton.Value
                crossCoords = [0 0 -bL/2 bL/2; -bL/2 bL/2 0 0];
                Screen('DrawLines',app.screenWindowHandle,crossCoords,bT,foregroundColor,app.screenCenterCoord);
                Screen('Flip',app.screenWindowHandle);
                
            elseif app.FaceButton.Value
                
                if app.MouthButton.Value
                    nose = nH(1);
                    if app.ShortButton.Value
                        mouth=mW(1);
                    else
                        mouth=mW(2);
                    end
                else
                    mouth=mW(1);
                    if app.ShortButton.Value
                        nose=nH(1);
                    else
                        nose=nH(2);
                    end
                end
                
                mCoords = [-mouth/2 mouth/2; mY mY];
                nCoords = [0 0; -nose/2-nY nose/2-nY];
                
                hRect = [0 0 hR*2 hR*2];
                hCRect = CenterRectOnPointd(hRect, app.screenCenterCoord(1), app.screenCenterCoord(2));
                
                eRect = [0 0 eR*2 eR*2];
                eSep = [eD/2 0 eD/2 0];
                eHeight = [0 -eY 0 -eY];
                eCRect = CenterRectOnPointd(eRect, app.screenCenterCoord(1), app.screenCenterCoord(2));
                eRectCoords = [eCRect-eSep+eHeight; eCRect+eSep+eHeight]';
                
                
                Screen('FrameOval',app.screenWindowHandle,foregroundColor,hCRect,hT);
                
                Screen('FillOval', app.screenWindowHandle,foregroundColor,eRectCoords);
                
                Screen('DrawLines', app.screenWindowHandle, nCoords,...
                    nT, foregroundColor, app.screenCenterCoord);
                
                Screen('DrawLines', app.screenWindowHandle, mCoords,...
                    mT, foregroundColor, app.screenCenterCoord);
                
                Screen('Flip',app.screenWindowHandle);
                
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, configFilename, configDirectory)
            app.MainApp = mainapp;
            
            scn=get(0,'ScreenSize');
            sx=scn(3)/3;
            sy=scn(4)/4;
            app.StimulusConfigurationModeUIFigure.Position(1) = sx;
            app.StimulusConfigurationModeUIFigure.Position(2) = sy;
            
            Screen('Preference', 'SkipSyncTests', 1);
            app.screens=Screen('Screens');
            app.screenNumber=max(app.screens);
            [app.screenWindowHandle,app.screenWindowRect] = Screen('OpenWindow',app.screenNumber,BlackIndex(app.screenNumber));
            [app.screenCenterCoord(1), app.screenCenterCoord(2)] = RectCenter(app.screenWindowRect);
            
            if ~isempty(configFilename) && ischar(configFilename)
                filename = configFilename;
            else
                filename = 'configDefault.mat';
            end
            path = configDirectory;
            configData = load([path,filename]);
            
            if strcmpi(configData.bg,'White')
                app.BlackButton.Value = false;
                app.WhiteButton.Value = true;
            end
            
            app.SizeSlider.Value = configData.bL;
            app.ThicknessSlider.Value = configData.bT;
            app.SizeSlider_2.Value = configData.hR;
            app.ThicknessSlider_2.Value = configData.hT;
            app.SizeSlider_3.Value = configData.eR;
            app.DistanceSlider.Value = configData.eD;
            app.YPositionSlider.Value = -configData.eY;
            app.ShortNoseHeightSlider.Value = configData.nH(1);
            app.LongNoseHeightSlider.Value = configData.nH(2);
            app.ThicknessSlider_3.Value = configData.nT;
            app.YPositionSlider_2.Value = configData.nY;
            app.ShortMouthWidthSlider.Value = configData.mW(1);
            app.LongMouthWidthSlider.Value = configData.mW(2);
            app.ThicknessSlider_4.Value = configData.mT;
            app.YPositionSlider_3.Value = configData.mY;
            app.ScaleSlider.Value = configData.sS;
            
            DisplaySample(app);
        end

        % Value changed function: SizeSlider
        function SizeSliderValueChanged(app, event)
            DisplaySample(app);
        end

        % Value changed function: ThicknessSlider
        function ThicknessSliderValueChanged(app, event)
            DisplaySample(app);
        end

        % Value changed function: SizeSlider_2
        function SizeSlider_2ValueChanged(app, event)
            DisplaySample(app);
        end

        % Value changed function: ThicknessSlider_2
        function ThicknessSlider_2ValueChanged(app, event)
            if app.IndependentCheckBox.Value==false
                app.ThicknessSlider_2.Value = app.ThicknessSlider_2.Value;
                app.ThicknessSlider_3.Value = app.ThicknessSlider_2.Value;
                app.ThicknessSlider_4.Value = app.ThicknessSlider_2.Value;
            end
            DisplaySample(app);
        end

        % Value changed function: SizeSlider_3
        function SizeSlider_3ValueChanged(app, event)
            DisplaySample(app);
        end

        % Value changed function: DistanceSlider
        function DistanceSliderValueChanged(app, event)
            DisplaySample(app);
        end

        % Value changed function: YPositionSlider
        function YPositionSliderValueChanged(app, event)
            DisplaySample(app);
        end

        % Value changed function: ShortNoseHeightSlider
        function ShortNoseHeightSliderValueChanged(app, event)
            DisplaySample(app);
        end

        % Value changed function: LongNoseHeightSlider
        function LongNoseHeightSliderValueChanged(app, event)
            DisplaySample(app);
        end

        % Value changed function: ThicknessSlider_3
        function ThicknessSlider_3ValueChanged(app, event)
            if app.IndependentCheckBox.Value==false
                app.ThicknessSlider_2.Value = app.ThicknessSlider_3.Value;
                app.ThicknessSlider_3.Value = app.ThicknessSlider_3.Value;
                app.ThicknessSlider_4.Value = app.ThicknessSlider_3.Value;
            end
            DisplaySample(app);
        end

        % Value changed function: YPositionSlider_2
        function YPositionSlider_2ValueChanged(app, event)
            DisplaySample(app);
        end

        % Value changed function: ShortMouthWidthSlider
        function ShortMouthWidthSliderValueChanged(app, event)
            DisplaySample(app);
        end

        % Value changed function: LongMouthWidthSlider
        function LongMouthWidthSliderValueChanged(app, event)
            DisplaySample(app);
        end

        % Value changed function: ThicknessSlider_4
        function ThicknessSlider_4ValueChanged(app, event)
            if app.IndependentCheckBox.Value==false
                app.ThicknessSlider_2.Value = app.ThicknessSlider_4.Value;
                app.ThicknessSlider_3.Value = app.ThicknessSlider_4.Value;
                app.ThicknessSlider_4.Value = app.ThicknessSlider_4.Value;
            end
            DisplaySample(app);
        end

        % Value changed function: YPositionSlider_3
        function YPositionSlider_3ValueChanged(app, event)
            DisplaySample(app);
        end

        % Selection changed function: BackgroundButtonGroup
        function BackgroundButtonGroupSelectionChanged(app, event)
            DisplaySample(app);
        end

        % Selection changed function: DisplayObjectButtonGroup
        function DisplayObjectButtonGroupSelectionChanged(app, event)
            DisplaySample(app);
        end

        % Selection changed function: StimulusVersionButtonGroup
        function StimulusVersionButtonGroupSelectionChanged(app, event)
            DisplaySample(app);
        end

        % Selection changed function: StimulusButtonGroup
        function StimulusButtonGroupSelectionChanged(app, event)
            DisplaySample(app);
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.SizeSlider.Value = 50;
            app.ThicknessSlider.Value = 5;
            app.SizeSlider_2.Value = 300;
            app.ThicknessSlider_2.Value = 5;
            app.SizeSlider_3.Value = 30;
            app.YPositionSlider_2.Value = 100;
            app.DistanceSlider.Value = 200;
            app.ShortNoseHeightSlider.Value = 100;
            app.LongNoseHeightSlider.Value = 150;
            app.YPositionSlider_2.Value = 0;
            app.ThicknessSlider_3.Value = 5;
            app.ShortMouthWidthSlider.Value = 200;
            app.LongMouthWidthSlider.Value = 250;
            app.ThicknessSlider_4.Value = 5;
            app.YPositionSlider_3.Value = 150;
            app.ScaleSlider.Value = 0;
            
            DisplaySample(app)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            sca
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename,path] = uiputfile('*.mat');
            if filename==0
                return
            end
            bg = app.background;
            bL = app.barLength;
            bT = app.barThickness;
            hR = app.headRadius;
            hT = app.headThickness;
            eR = app.eyeRadius;
            eD = app.eyeDistance;
            eY = app.eyeYPos;
            nH = app.noseHeights;
            nT = app.noseThickness;
            nY = app.noseYPos;
            mW = app.mouthWidths;
            mT = app.mouthThickness;
            mY = app.mouthYPos;
            sS = app.slideScalar;
            
            save([path,filename],'bg','bL','bT','hR','hT',...
                'eR','eD','eY','nH','nT','nY',...
                'mW','mT','mY','sS');
            
            UpdateStimulusConfiguration(app.MainApp,app.background,app.barLength,app.barThickness,app.headRadius,app.headThickness,app.noseHeights,app.noseThickness,app.noseYPos,app.mouthWidths,app.mouthThickness,app.mouthYPos,app.eyeRadius,app.eyeDistance,app.eyeYPos,app.slideScalar);
            app.MainApp.StimuliConfigurationFileEditField.Value = filename;
        end

        % Close request function: StimulusConfigurationModeUIFigure
        function StimulusConfigurationModeUIFigureCloseRequest(app, event)
            sca
            delete(app)
        end

        % Value changed function: ScaleSlider
        function ScaleSliderValueChanged(app, event)
DisplaySample(app)
        end

        % Value changed function: IndependentCheckBox
        function IndependentCheckBoxValueChanged(app, event)
            if app.IndependentCheckBox.Value
                app.ThicknessSlider_2.Value=5;
                app.ThicknessSlider_3.Value=5;
                app.ThicknessSlider_4.Value=5;
            end
            DisplaySample(app)
        end

        % Value changed function: ScaleFreeCheckBox
        function ScaleFreeCheckBoxValueChanged(app, event)
            DisplaySample(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StimulusConfigurationModeUIFigure = uifigure('Visible', 'off');
            app.StimulusConfigurationModeUIFigure.Name = 'Stimulus Configuration Mode';
            app.StimulusConfigurationModeUIFigure.CloseRequestFcn = createCallbackFcn(app, @StimulusConfigurationModeUIFigureCloseRequest, true);
            app.FixationCrossPanel = uipanel(app.StimulusConfigurationModeUIFigure);
            app.FixationCrossPanel.Title = 'Fixation Cross';
            app.SizeSliderLabel = uilabel(app.FixationCrossPanel);
            app.SizeSliderLabel.Text = 'Size';
            app.SizeSlider = uislider(app.FixationCrossPanel);
            app.SizeSlider.MajorTicks = [0 50 100];
            app.SizeSlider.MajorTickLabels = {''};
            app.SizeSlider.ValueChangedFcn = createCallbackFcn(app, @SizeSliderValueChanged, true);
            app.SizeSlider.Value = 50;
            app.ThicknessSliderLabel = uilabel(app.FixationCrossPanel);
            app.ThicknessSliderLabel.Text = 'Thickness';
            app.ThicknessSlider = uislider(app.FixationCrossPanel);
            app.ThicknessSlider.Limits = [1 50];
            app.ThicknessSlider.MajorTicks = [1 25 50];
            app.ThicknessSlider.MajorTickLabels = {''};
            app.ThicknessSlider.ValueChangedFcn = createCallbackFcn(app, @ThicknessSliderValueChanged, true);
            app.ThicknessSlider.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.ThicknessSlider.Value = 5;
            app.HeadPanel = uipanel(app.StimulusConfigurationModeUIFigure);
            app.HeadPanel.Title = 'Head';
            app.SizeSlider_2Label = uilabel(app.HeadPanel);
            app.SizeSlider_2Label.Text = 'Size';
            app.SizeSlider_2 = uislider(app.HeadPanel);
            app.SizeSlider_2.Limits = [0 600];
            app.SizeSlider_2.MajorTicks = [0 300 600];
            app.SizeSlider_2.MajorTickLabels = {''};
            app.SizeSlider_2.ValueChangedFcn = createCallbackFcn(app, @SizeSlider_2ValueChanged, true);
            app.SizeSlider_2.MinorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360 390 420 450 480 510 540 570 600];
            app.SizeSlider_2.Value = 300;
            app.ThicknessSlider_2Label = uilabel(app.HeadPanel);
            app.ThicknessSlider_2Label.Text = 'Thickness';
            app.ThicknessSlider_2 = uislider(app.HeadPanel);
            app.ThicknessSlider_2.Limits = [1 50];
            app.ThicknessSlider_2.MajorTicks = [1 25 50];
            app.ThicknessSlider_2.MajorTickLabels = {''};
            app.ThicknessSlider_2.ValueChangedFcn = createCallbackFcn(app, @ThicknessSlider_2ValueChanged, true);
            app.ThicknessSlider_2.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.ThicknessSlider_2.Value = 5;
            app.EyesPanel = uipanel(app.StimulusConfigurationModeUIFigure);
            app.EyesPanel.Title = 'Eyes';
            app.SizeSlider_3Label = uilabel(app.EyesPanel);
            app.SizeSlider_3Label.Text = 'Size';
            app.SizeSlider_3 = uislider(app.EyesPanel);
            app.SizeSlider_3.Limits = [0 60];
            app.SizeSlider_3.MajorTicks = [0 30 60];
            app.SizeSlider_3.MajorTickLabels = {''};
            app.SizeSlider_3.ValueChangedFcn = createCallbackFcn(app, @SizeSlider_3ValueChanged, true);
            app.SizeSlider_3.MinorTicks = [0 3 6 9 12 15 18 21 24 27 30 33 36 39 42 45 48 51 54 57 60];
            app.SizeSlider_3.Value = 30;
            app.YPositionSliderLabel = uilabel(app.EyesPanel);
            app.YPositionSliderLabel.Text = 'Y Position';
            app.YPositionSlider = uislider(app.EyesPanel);
            app.YPositionSlider.Limits = [0 200];
            app.YPositionSlider.MajorTicks = [0 100 200];
            app.YPositionSlider.MajorTickLabels = {''};
            app.YPositionSlider.ValueChangedFcn = createCallbackFcn(app, @YPositionSliderValueChanged, true);
            app.YPositionSlider.Value = 100;
            app.DistanceSliderLabel = uilabel(app.EyesPanel);
            app.DistanceSliderLabel.Text = 'Distance';
            app.DistanceSlider = uislider(app.EyesPanel);
            app.DistanceSlider.Limits = [0 400];
            app.DistanceSlider.MajorTicks = [0 200 400];
            app.DistanceSlider.MajorTickLabels = {''};
            app.DistanceSlider.ValueChangedFcn = createCallbackFcn(app, @DistanceSliderValueChanged, true);
            app.DistanceSlider.Value = 200;
            app.NosePanel = uipanel(app.StimulusConfigurationModeUIFigure);
            app.NosePanel.Title = 'Nose';
            app.ShortNoseHeightSliderLabel = uilabel(app.NosePanel);
            app.ShortNoseHeightSliderLabel.Text = 'Short Nose Height';
            app.ShortNoseHeightSlider = uislider(app.NosePanel);
            app.ShortNoseHeightSlider.Limits = [0 200];
            app.ShortNoseHeightSlider.MajorTicks = [0 50 100 150 200];
            app.ShortNoseHeightSlider.MajorTickLabels = {''};
            app.ShortNoseHeightSlider.ValueChangedFcn = createCallbackFcn(app, @ShortNoseHeightSliderValueChanged, true);
            app.ShortNoseHeightSlider.Value = 100;
            app.LongNoseHeightSliderLabel = uilabel(app.NosePanel);
            app.LongNoseHeightSliderLabel.Text = 'Long Nose Height';
            app.LongNoseHeightSlider = uislider(app.NosePanel);
            app.LongNoseHeightSlider.Limits = [0 200];
            app.LongNoseHeightSlider.MajorTicks = [0 50 100 150 200];
            app.LongNoseHeightSlider.MajorTickLabels = {''};
            app.LongNoseHeightSlider.ValueChangedFcn = createCallbackFcn(app, @LongNoseHeightSliderValueChanged, true);
            app.LongNoseHeightSlider.Value = 150;
            app.ThicknessSlider_3Label = uilabel(app.NosePanel);
            app.ThicknessSlider_3Label.Text = 'Thickness';
            app.ThicknessSlider_3 = uislider(app.NosePanel);
            app.ThicknessSlider_3.Limits = [1 50];
            app.ThicknessSlider_3.MajorTicks = [1 25 50];
            app.ThicknessSlider_3.MajorTickLabels = {''};
            app.ThicknessSlider_3.ValueChangedFcn = createCallbackFcn(app, @ThicknessSlider_3ValueChanged, true);
            app.ThicknessSlider_3.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.ThicknessSlider_3.Value = 5;
            app.YPositionSlider_2Label = uilabel(app.NosePanel);
            app.YPositionSlider_2Label.Text = 'Y Position';
            app.YPositionSlider_2 = uislider(app.NosePanel);
            app.YPositionSlider_2.Limits = [-100 100];
            app.YPositionSlider_2.MajorTicks = [-100 0 100];
            app.YPositionSlider_2.MajorTickLabels = {''};
            app.YPositionSlider_2.ValueChangedFcn = createCallbackFcn(app, @YPositionSlider_2ValueChanged, true);
            app.MouthPanel = uipanel(app.StimulusConfigurationModeUIFigure);
            app.MouthPanel.Title = 'Mouth';
            app.ShortMouthWidthSliderLabel = uilabel(app.MouthPanel);
            app.ShortMouthWidthSliderLabel.Text = 'Short Mouth Width';
            app.ShortMouthWidthSlider = uislider(app.MouthPanel);
            app.ShortMouthWidthSlider.Limits = [0 400];
            app.ShortMouthWidthSlider.MajorTicks = [0 100 200 300 400];
            app.ShortMouthWidthSlider.MajorTickLabels = {''};
            app.ShortMouthWidthSlider.ValueChangedFcn = createCallbackFcn(app, @ShortMouthWidthSliderValueChanged, true);
            app.ShortMouthWidthSlider.Value = 200;
            app.LongMouthWidthSliderLabel = uilabel(app.MouthPanel);
            app.LongMouthWidthSliderLabel.Text = 'Long Mouth Width';
            app.LongMouthWidthSlider = uislider(app.MouthPanel);
            app.LongMouthWidthSlider.Limits = [0 400];
            app.LongMouthWidthSlider.MajorTicks = [0 100 200 300 400];
            app.LongMouthWidthSlider.MajorTickLabels = {''};
            app.LongMouthWidthSlider.ValueChangedFcn = createCallbackFcn(app, @LongMouthWidthSliderValueChanged, true);
            app.LongMouthWidthSlider.Value = 250;
            app.ThicknessSlider_4Label = uilabel(app.MouthPanel);
            app.ThicknessSlider_4Label.Text = 'Thickness';
            app.ThicknessSlider_4 = uislider(app.MouthPanel);
            app.ThicknessSlider_4.Limits = [1 50];
            app.ThicknessSlider_4.MajorTicks = [1 25 50];
            app.ThicknessSlider_4.MajorTickLabels = {''};
            app.ThicknessSlider_4.ValueChangedFcn = createCallbackFcn(app, @ThicknessSlider_4ValueChanged, true);
            app.ThicknessSlider_4.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.ThicknessSlider_4.Value = 5;
            app.YPositionSlider_3Label = uilabel(app.MouthPanel);
            app.YPositionSlider_3Label.Text = 'Y Position';
            app.YPositionSlider_3 = uislider(app.MouthPanel);
            app.YPositionSlider_3.Limits = [0 300];
            app.YPositionSlider_3.MajorTicks = [0 150 300];
            app.YPositionSlider_3.MajorTickLabels = {''};
            app.YPositionSlider_3.ValueChangedFcn = createCallbackFcn(app, @YPositionSlider_3ValueChanged, true);
            app.YPositionSlider_3.MinorTicks = [0 30 60 90 120 150 180 210 240 270 300];
            app.YPositionSlider_3.Value = 150;
            app.DisplayObjectButtonGroup = uibuttongroup(app.StimulusConfigurationModeUIFigure);
            app.DisplayObjectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayObjectButtonGroupSelectionChanged, true);
            app.DisplayObjectButtonGroup.Title = 'Display Object';
            app.FixationCrossButton = uiradiobutton(app.DisplayObjectButtonGroup);
            app.FixationCrossButton.Text = 'Fixation Cross';
            app.FixationCrossButton.Value = true;
            app.FaceButton = uiradiobutton(app.DisplayObjectButtonGroup);
            app.FaceButton.Text = 'Face';
            app.StimulusVersionButtonGroup = uibuttongroup(app.StimulusConfigurationModeUIFigure);
            app.StimulusVersionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimulusVersionButtonGroupSelectionChanged, true);
            app.StimulusVersionButtonGroup.Title = 'Stimulus Version';
            app.MouthButton = uiradiobutton(app.StimulusVersionButtonGroup);
            app.MouthButton.Text = 'Mouth';
            app.MouthButton.Value = true;
            app.NoseButton = uiradiobutton(app.StimulusVersionButtonGroup);
            app.NoseButton.Text = 'Nose';
            app.StimulusButtonGroup = uibuttongroup(app.StimulusConfigurationModeUIFigure);
            app.StimulusButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimulusButtonGroupSelectionChanged, true);
            app.StimulusButtonGroup.Title = 'Stimulus';
            app.ShortButton = uiradiobutton(app.StimulusButtonGroup);
            app.ShortButton.Text = 'Short';
            app.ShortButton.Value = true;
            app.LongButton = uiradiobutton(app.StimulusButtonGroup);
            app.LongButton.Text = 'Long';
            app.BackgroundButtonGroup = uibuttongroup(app.StimulusConfigurationModeUIFigure);
            app.BackgroundButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BackgroundButtonGroupSelectionChanged, true);
            app.BackgroundButtonGroup.Title = 'Background';
            app.BlackButton = uiradiobutton(app.BackgroundButtonGroup);
            app.BlackButton.Text = 'Black';
            app.BlackButton.Value = true;
            app.WhiteButton = uiradiobutton(app.BackgroundButtonGroup);
            app.WhiteButton.Text = 'White';
            app.DefaultButton = uibutton(app.StimulusConfigurationModeUIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.DoneButton = uibutton(app.StimulusConfigurationModeUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.SaveButton = uibutton(app.StimulusConfigurationModeUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ScaleSliderLabel = uilabel(app.StimulusConfigurationModeUIFigure);
            app.ScaleSliderLabel.Text = 'Scale';
            app.ScaleSlider = uislider(app.StimulusConfigurationModeUIFigure);
            app.ScaleSlider.Limits = [-3 3];
            app.ScaleSlider.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.ScaleSlider.MajorTickLabels = {''};
            app.ScaleSlider.ValueChangedFcn = createCallbackFcn(app, @ScaleSliderValueChanged, true);
            app.LineStylePanel = uipanel(app.StimulusConfigurationModeUIFigure);
            app.LineStylePanel.Title = 'Line Style';
            app.IndependentCheckBox = uicheckbox(app.LineStylePanel);
            app.IndependentCheckBox.ValueChangedFcn = createCallbackFcn(app, @IndependentCheckBoxValueChanged, true);
            app.IndependentCheckBox.Text = 'Independent';
            app.IndependentCheckBox.Value = true;
            app.ScaleFreeCheckBox = uicheckbox(app.LineStylePanel);
            app.ScaleFreeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleFreeCheckBoxValueChanged, true);
            app.ScaleFreeCheckBox.Text = 'Scale-Free';
            % Show the figure after all components are created
            app.StimulusConfigurationModeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRTConfigureDualScrn(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StimulusConfigurationModeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StimulusConfigurationModeUIFigure)
        end
    end
end