classdef PRTSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PRTSetupUIFigure               matlab.ui.Figure
        BrowseButton                   matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        ViewCustomizeButton            matlab.ui.control.Button
        SubjectIDEditFieldLabel        matlab.ui.control.Label
        SubjectIDEditField             matlab.ui.control.EditField
        WorkingDirectoryLabel          matlab.ui.control.Label
        WorkingDirectoryEditField      matlab.ui.control.EditField
        SessionEditField_2Label        matlab.ui.control.Label
        SessionEditField               matlab.ui.control.NumericEditField
        InputConfigurationPanel        matlab.ui.container.Panel
        ChangeInputsButton             matlab.ui.control.Button
        ShortKeyEditFieldLabel         matlab.ui.control.Label
        ShortKeyEditField              matlab.ui.control.EditField
        LongKeyEditFieldLabel          matlab.ui.control.Label
        LongKeyEditField               matlab.ui.control.EditField
        ExperimentSetupPanel           matlab.ui.container.Panel
        VersionDropDownLabel           matlab.ui.control.Label
        VersionDropDown                matlab.ui.control.DropDown
        RichBiasDropDownLabel          matlab.ui.control.Label
        RichBiasDropDown               matlab.ui.control.DropDown
        WinningsDropDownLabel          matlab.ui.control.Label
        WinningsDropDown               matlab.ui.control.DropDown
        BlocksEditFieldLabel           matlab.ui.control.Label
        BlocksEditField                matlab.ui.control.NumericEditField
        TrialsperBlockLabel            matlab.ui.control.Label
        TrialsperBlockEditField        matlab.ui.control.NumericEditField
        StimuliConfigurationFileLabel  matlab.ui.control.Label
        StimuliConfigurationFileEditField  matlab.ui.control.EditField
        LoadButton                     matlab.ui.control.Button
        RunIntroCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        ConfigureApp
        screens = Screen('Screens');
        
        subjectID = 'Test';
        sessionNumber = 356;
        shortKeyMap = 'v';
        longKeyMap = 'm';
        stimulusVersion = 'Mouth';
        blockNumber = 2;
        trialsPerBlock = 100;
        richStimuli = 'Long';
        leanStimuli = 'Short';
        finalPrice = '$15.80';
        runIntro = true;
        
        background = 'Black';
        barLength = 50;
        barThickness = 5;
        headRadius = 300;
        headThickness = 5;
        noseHeights = [100 150]
        noseThickness = 5;
        noseYPos = 0;
        mouthWidths = [200 250];
        mouthThickness = 5;
        mouthYPos = 150;
        eyeRadius = 30;
        eyeDistance = 200;
        eyeYPos = -100;
        slideScalar = 0;

        configDirectory
        configFilename
    end   
    
    methods (Access = public)
        
        function UpdateStimulusConfiguration(app,bg,bL,bT,hR,hT,nH,nT,nY,mW,mT,mY,eR,eD,eY,sS)
            app.background = bg;
            app.barLength = bL;
            app.barThickness = bT;
            app.headRadius = hR;
            app.headThickness = hT;
            app.noseHeights = nH;
            app.noseThickness = nT;
            app.noseYPos = nY;
            app.mouthWidths = mW;
            app.mouthThickness = mT;
            app.mouthYPos = mY;
            app.eyeRadius = eR;
            app.eyeDistance = eD;
            app.eyeYPos = eY;
            app.slideScalar = sS;
        end
        
    end
    
    methods (Access = private)
        function createDefaultConfig(app)
            bg = app.background;
            bL = app.barLength;
            bT = app.barThickness;
            hR = app.headRadius;
            hT = app.headThickness;
            eR = app.eyeRadius;
            eD = app.eyeDistance;
            eY = app.eyeYPos;
            nH = app.noseHeights;
            nT = app.noseThickness;
            nY = app.noseYPos;
            mW = app.mouthWidths;
            mT = app.mouthThickness;
            mY = app.mouthYPos;
            sS = app.slideScalar;
            
            filename = 'configDefault.mat';
            save([pwd,filesep,'config',filesep,filename],'bg','bL','bT',...
                'hR','hT','eR','eD','eY',...
                'nH','nT','nY',...
                'mW','mT','mY','sS')
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scn=get(0,'ScreenSize');
            sx=scn(3)/3;
            sy=scn(4)/2;
            app.PRTSetupUIFigure.Position(1) = sx;
            app.PRTSetupUIFigure.Position(2) = sy;
            KbName('UnifyKeyNames');
            
            if ~exist([pwd,filesep,'PRT.m'],'file')
                uialert(app.PRTSetupUIFigure,'Incorrect Directory. Please select PRT.m file','Wrong Directory')
                [~,wd]=uigetfile('All MATLAB Files','Select PRT.m File');
                cd(wd)
            end
            app.WorkingDirectoryEditField.Value = pwd;
            if ~exist([pwd,filesep,'Results'],'dir')
                mkdir([pwd,filesep,'Results'])
            end
            addpath([pwd,filesep,'Results'])
            if ~exist([pwd,filesep,'config'],'dir')
                mkdir([pwd,filesep,'config'])
                app.createDefaultConfig()
            end
            addpath([pwd,filesep,'config'])
            if ~exist([pwd,filesep,'config',filesep,'configDefault.mat'],'file')
                app.createDefaultConfig()
            end
            
            %Set Default Values
            app.SubjectIDEditField.Value = app.subjectID;
            app.SessionEditField.Value = app.sessionNumber;
            app.VersionDropDown.Value = app.stimulusVersion;
            app.RichBiasDropDown.Value = app.richStimuli;
            app.WinningsDropDown.Value = app.finalPrice;
            app.BlocksEditField.Value = app.blockNumber;
            app.TrialsperBlockEditField.Value = app.trialsPerBlock;
            app.ShortKeyEditField.Value = app.shortKeyMap;
            app.LongKeyEditField.Value = app.longKeyMap;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Assign Variables to the Workspace
            assignin('base','saveDir',[app.WorkingDirectoryEditField.Value,filesep,'Results']);

            assignin('base','sessionNumber',app.sessionNumber);
            assignin('base','subjectID',app.subjectID);
            assignin('base','shortKeyMap',app.shortKeyMap);
            assignin('base','longKeyMap',app.longKeyMap);
            assignin('base','stimulusVersion',app.stimulusVersion);
            assignin('base','blockNumber',app.blockNumber);
            assignin('base','trialsPerBlock',app.trialsPerBlock);
            switch app.RichBiasDropDown.Value
                case 'Long'
                    assignin('base','richStimuli',app.richStimuli);
                    assignin('base','leanStimuli','Short');
                case 'Short'
                    assignin('base','richStimuli',app.richStimuli);
                    assignin('base','leanStimuli','Long')
            end
            assignin('base','finalPrice',app.finalPrice);
            
            assignin('base','background',app.background);
            assignin('base','barLength',app.barLength);
            assignin('base','barThickness',app.barThickness);
            assignin('base','headRadius',app.headRadius);
            assignin('base','headThickness',app.headThickness);
            assignin('base','noseHeights',app.noseHeights);
            assignin('base','noseThickness',app.noseThickness);
            assignin('base','noseYPos',app.noseYPos);
            assignin('base','mouthWidths',app.mouthWidths);
            assignin('base','mouthThickness',app.mouthThickness);
            assignin('base','mouthYPos',app.mouthYPos);
            assignin('base','eyeRadius',app.eyeRadius);
            assignin('base','eyeDistance',app.eyeDistance);
            assignin('base','eyeYPos',app.eyeYPos);
            assignin('base','slideScalar',app.slideScalar);
            assignin('base','runIntro',app.runIntro);
            
            delete(app)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            value = uigetdir(pwd,'Please Select the Working Directory for PRT');
            if value==0 % Cancel
                return
            end
            cd(value)
            app.WorkingDirectoryEditField.Value = value;
        end

        % Button pushed function: ChangeInputsButton
        function ChangeInputsButtonPushed(app, event)
            ListenChar(2)
%             uialert(app.PRTSetupUIFigure,'Please press the ''Short'' Key','Short Key');
            d=uiprogressdlg(app.PRTSetupUIFigure,'Title','Short Key','Message','Please press the ''Short'' Key');
            value = KbName();
            app.ShortKeyEditField.Value = value;
            app.shortKeyMap = value;
            close(d)
%             uialert(app.PRTSetupUIFigure,'Please press the ''Long'' Key','Long Key');
            d=uiprogressdlg(app.PRTSetupUIFigure,'Title','Long Key','Message','Please press the ''Long'' Key');
            value = KbName();
            close(d)
            app.LongKeyEditField.Value = value;
            app.longKeyMap = value;
            ListenChar();
        end

        % Button pushed function: ViewCustomizeButton
        function ViewCustomizeButtonPushed(app, event)
            if length(app.screens)>1
                selection = uiconfirm(app.PRTSetupUIFigure,{'Multiple monitors detected. Please select configuration mode.','','Note: Dual Monitor utilizes Psychtoolbox''s Fullscreen Mode.'},'Select Mode','Options',{'Dual Monitor','Single Monitor','Cancel'},'DefaultOption',2,'CancelOption',3,'Icon','');
                switch selection
                    case 'Dual Monitor'
                        app.ConfigureApp = PRTConfigureDualScrn(app,app.StimuliConfigurationFileEditField.Value,app.configDirectory);
                    case 'Single Monitor'
                        app.ConfigureApp = PRTConfigureSingle(app,app.StimuliConfigurationFileEditField.Value,app.configDirectory);
                end
            else
                app.ConfigureApp = PRTConfigureSingle(app,app.StimuliConfigurationFileEditField.Value,app.configDirectory);
            end
        end

        % Close request function: PRTSetupUIFigure
        function PRTSetupUIFigureCloseRequest(app, event)
            
            delete(app.ConfigureApp)

            sca
            delete(app)
            
        end

        % Value changed function: SubjectIDEditField
        function SubjectIDEditFieldValueChanged(app, event)
            app.subjectID = app.SubjectIDEditField.Value;
            
        end

        % Value changed function: SessionEditField
        function SessionEditFieldValueChanged(app, event)
            app.sessionNumber = app.SessionEditField.Value;
            
        end

        % Value changed function: VersionDropDown
        function VersionDropDownValueChanged(app, event)
            app.stimulusVersion = app.VersionDropDown.Value;
            
        end

        % Value changed function: RichBiasDropDown
        function RichBiasDropDownValueChanged(app, event)
            app.richStimuli = app.RichBiasDropDown.Value;
            
        end

        % Value changed function: WinningsDropDown
        function WinningsDropDownValueChanged(app, event)
            app.finalPrice = app.WinningsDropDown.Value;
            
        end

        % Value changed function: BlocksEditField
        function BlocksEditFieldValueChanged(app, event)
            app.blockNumber = app.BlocksEditField.Value;
            
        end

        % Value changed function: TrialsperBlockEditField
        function TrialsperBlockEditFieldValueChanged(app, event)
        if app.TrialsperBlockEditField.Value < 3
            app.trialsPerBlock = 3;
            app.TrialsperBlockEditField.Value = 3;
        end
        app.trialsPerBlock = app.TrialsperBlockEditField.Value;
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename,path]=uigetfile('*.mat','Select Configuration File');
            if filename==0
                return
            end
            app.configDirectory = path;
            app.configFilename = filename;
            configData = load([path,filesep,filename]);
            app.background = configData.bg;
            app.barLength = configData.bL;
            app.barThickness = configData.bT;
            app.headRadius = configData.hR;
            app.headThickness = configData.hT;
            app.eyeRadius = configData.eR;
            app.eyeDistance = configData.eD;
            app.eyeYPos = configData.eY;
            app.noseHeights = configData.nH;
            app.noseThickness = configData.nT;
            app.noseYPos = configData.nY;
            app.mouthWidths = configData.mW;
            app.mouthThickness = configData.mT;
            app.mouthYPos = configData.mY;
            app.slideScalar = configData.sS;
            
            app.StimuliConfigurationFileEditField.Value = app.configFilename;
            
        end

        % Value changed function: RunIntroCheckBox
        function RunIntroCheckBoxValueChanged(app, event)
            app.runIntro = app.RunIntroCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PRTSetupUIFigure = uifigure('Visible', 'off');
            app.PRTSetupUIFigure.Name = 'PRT Setup';
            app.PRTSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @PRTSetupUIFigureCloseRequest, true);
            app.BrowseButton = uibutton(app.PRTSetupUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.StartButton = uibutton(app.PRTSetupUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ViewCustomizeButton = uibutton(app.PRTSetupUIFigure, 'push');
            app.ViewCustomizeButton.ButtonPushedFcn = createCallbackFcn(app, @ViewCustomizeButtonPushed, true);
            app.ViewCustomizeButton.Text = 'View/Customize';
            app.SubjectIDEditFieldLabel = uilabel(app.PRTSetupUIFigure);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.PRTSetupUIFigure, 'text');
            app.SubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditFieldValueChanged, true);
            app.SubjectIDEditField.Value = 'Test';
            app.WorkingDirectoryLabel = uilabel(app.PRTSetupUIFigure);
            app.WorkingDirectoryLabel.Text = 'Working Directory:';
            app.WorkingDirectoryEditField = uieditfield(app.PRTSetupUIFigure, 'text');
            app.SessionEditField_2Label = uilabel(app.PRTSetupUIFigure);
            app.SessionEditField_2Label.Text = 'Session #';
            app.SessionEditField = uieditfield(app.PRTSetupUIFigure, 'numeric');
            app.SessionEditField.ValueChangedFcn = createCallbackFcn(app, @SessionEditFieldValueChanged, true);
            app.SessionEditField.Value = 356;
            app.InputConfigurationPanel = uipanel(app.PRTSetupUIFigure);
            app.InputConfigurationPanel.Title = 'Input Configuration';
            app.ChangeInputsButton = uibutton(app.InputConfigurationPanel, 'push');
            app.ChangeInputsButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeInputsButtonPushed, true);
            app.ChangeInputsButton.Text = 'Change Inputs';
            app.ShortKeyEditFieldLabel = uilabel(app.InputConfigurationPanel);
            app.ShortKeyEditFieldLabel.Text = 'Short Key';
            app.ShortKeyEditField = uieditfield(app.InputConfigurationPanel, 'text');
            app.ShortKeyEditField.Editable = 'off';
            app.ShortKeyEditField.Value = 'v';
            app.LongKeyEditFieldLabel = uilabel(app.InputConfigurationPanel);
            app.LongKeyEditFieldLabel.Text = 'Long Key';
            app.LongKeyEditField = uieditfield(app.InputConfigurationPanel, 'text');
            app.LongKeyEditField.Editable = 'off';
            app.LongKeyEditField.Value = 'm';
            app.ExperimentSetupPanel = uipanel(app.PRTSetupUIFigure);
            app.ExperimentSetupPanel.Title = 'Experiment Setup';
            app.VersionDropDownLabel = uilabel(app.ExperimentSetupPanel);
            app.VersionDropDownLabel.Text = 'Version';
            app.VersionDropDown = uidropdown(app.ExperimentSetupPanel);
            app.VersionDropDown.Items = {'Mouth', 'Nose'};
            app.VersionDropDown.ValueChangedFcn = createCallbackFcn(app, @VersionDropDownValueChanged, true);
            app.VersionDropDown.Value = 'Mouth';
            app.RichBiasDropDownLabel = uilabel(app.ExperimentSetupPanel);
            app.RichBiasDropDownLabel.Text = 'Rich Bias';
            app.RichBiasDropDown = uidropdown(app.ExperimentSetupPanel);
            app.RichBiasDropDown.Items = {'Long', 'Short'};
            app.RichBiasDropDown.ValueChangedFcn = createCallbackFcn(app, @RichBiasDropDownValueChanged, true);
            app.RichBiasDropDown.Value = 'Long';
            app.WinningsDropDownLabel = uilabel(app.ExperimentSetupPanel);
            app.WinningsDropDownLabel.Text = 'Winnings';
            app.WinningsDropDown = uidropdown(app.ExperimentSetupPanel);
            app.WinningsDropDown.Items = {'$15.80', '$16.20'};
            app.WinningsDropDown.ValueChangedFcn = createCallbackFcn(app, @WinningsDropDownValueChanged, true);
            app.WinningsDropDown.Value = '$15.80';
            app.BlocksEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.BlocksEditFieldLabel.Text = 'Blocks';
            app.BlocksEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.BlocksEditField.ValueChangedFcn = createCallbackFcn(app, @BlocksEditFieldValueChanged, true);
            app.BlocksEditField.Value = 2;
            app.TrialsperBlockLabel = uilabel(app.ExperimentSetupPanel);
            app.TrialsperBlockLabel.Text = 'Trials per Block';
            app.TrialsperBlockEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.TrialsperBlockEditField.ValueChangedFcn = createCallbackFcn(app, @TrialsperBlockEditFieldValueChanged, true);
            app.TrialsperBlockEditField.Value = 100;
            app.StimuliConfigurationFileLabel = uilabel(app.PRTSetupUIFigure);
            app.StimuliConfigurationFileLabel.Text = 'Stimuli Configuration File:';
            app.StimuliConfigurationFileEditField = uieditfield(app.PRTSetupUIFigure, 'text');
            app.StimuliConfigurationFileEditField.Editable = 'off';
            app.LoadButton = uibutton(app.PRTSetupUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RunIntroCheckBox = uicheckbox(app.PRTSetupUIFigure);
            app.RunIntroCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunIntroCheckBoxValueChanged, true);
            app.RunIntroCheckBox.Text = 'Run Intro';
            app.RunIntroCheckBox.Value = true;
            % Show the figure after all components are created
            app.PRTSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRTSetup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PRTSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PRTSetupUIFigure)
        end
    end
end